classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure     matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        SavedAnglesLabel         matlab.ui.control.Label
        UpdateSavedStateButton   matlab.ui.control.Button
        GotoSavedStateButton     matlab.ui.control.Button
        SavedStateDropDown       matlab.ui.control.DropDown
        SavedStateDropDownLabel  matlab.ui.control.Label
        HomeButton               matlab.ui.control.Button
        GoButton                 matlab.ui.control.Button
        J3ValueEditField         matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel    matlab.ui.control.Label
        J2ValueEditField         matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel    matlab.ui.control.Label
        J1ValueEditField         matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel    matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        axes_arm                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0 0 0];

        savedStates = zeros(3,3);
    end

    methods (Access = private)

        function updateArm(app)

            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-160 250];
            % app.axes_arm.YLim = [-250 250];
            % app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("link0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("link1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("link2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("link3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()

            app.SavedStateDropDown.ItemsData = [1 2 3];
            app.SavedStateDropDown.Value = 1;

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            % Also update the numeric value text field.
            app.J1ValueEditField.Value = app.jointAngles(1);
            app.updateArm()

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm();
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.jointAngles(1) = 0;
            app.jointAngles(2) = 0;
            app.jointAngles(3) = 0;

            app.J1ValueEditField.Value = 0;
            app.J2ValueEditField.Value = 0;
            app.J3ValueEditField.Value = 0;

            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 0;

            app.updateArm();

        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.updateArm()

        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.J3ValueEditField.Value = app.jointAngles(3);
            app.updateArm()

        end

        % Value changed function: J1ValueEditField
        function J1ValueEditFieldValueChanged(app, event)

        end

        % Value changed function: J2ValueEditField
        function J2ValueEditFieldValueChanged(app, event)

        end

        % Value changed function: J3ValueEditField
        function J3ValueEditFieldValueChanged(app, event)

        end

        % Value changed function: SavedStateDropDown
        function SavedStateDropDownValueChanged(app, event)
            n = app.SavedStateDropDown.Value;

            app.SavedAnglesLabel.Text = num2str(round(app.savedStates(n,:), 1));
        end

        % Button pushed function: UpdateSavedStateButton
        function UpdateSavedStateButtonPushed(app, event)

            n = app.SavedStateDropDown.Value;

            app.savedStates(n,:) = app.jointAngles;

            app.SavedAnglesLabel.Text = num2str(round(app.savedStates(n,:), 1));

        end

        % Button pushed function: GotoSavedStateButton
        function GotoSavedStateButtonPushed(app, event)

            n = app.SavedStateDropDown.Value;
            app.jointAngles = app.savedStates(n,:);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.Joint1SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.AdeeptArmGUIUIFigure);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.J1ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J1ValueEditField.Limits = [-90 90];
            app.J1ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J1ValueEditFieldValueChanged, true);
            app.J2ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J2ValueEditField.Limits = [-90 90];
            app.J2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J2ValueEditFieldValueChanged, true);
            app.J3ValueEditFieldLabel = uilabel(app.AdeeptArmGUIUIFigure);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.AdeeptArmGUIUIFigure, 'numeric');
            app.J3ValueEditField.Limits = [-90 90];
            app.J3ValueEditField.ValueChangedFcn = createCallbackFcn(app, @J3ValueEditFieldValueChanged, true);
            app.GoButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.Panel = uipanel(app.AdeeptArmGUIUIFigure);
            app.Panel.Title = 'Panel';
            app.SavedStateDropDownLabel = uilabel(app.Panel);
            app.SavedStateDropDownLabel.Text = 'Saved State';
            app.SavedStateDropDown = uidropdown(app.Panel);
            app.SavedStateDropDown.Items = {'State 1', 'State 2', 'State 3'};
            app.SavedStateDropDown.ValueChangedFcn = createCallbackFcn(app, @SavedStateDropDownValueChanged, true);
            app.SavedStateDropDown.Value = 'State 1';
            app.GotoSavedStateButton = uibutton(app.Panel, 'push');
            app.GotoSavedStateButton.ButtonPushedFcn = createCallbackFcn(app, @GotoSavedStateButtonPushed, true);
            app.GotoSavedStateButton.Text = 'Go to Saved State';
            app.UpdateSavedStateButton = uibutton(app.Panel, 'push');
            app.UpdateSavedStateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSavedStateButtonPushed, true);
            app.UpdateSavedStateButton.Text = 'Update Saved State';
            app.SavedAnglesLabel = uilabel(app.Panel);
            app.SavedAnglesLabel.Text = '0 0 0';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end