classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResponseTextArea               matlab.ui.control.TextArea
        ResponseTextAreaLabel          matlab.ui.control.Label
        PeriodmsEditField              matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel         matlab.ui.control.Label
        NumberofFlashesEditField       matlab.ui.control.NumericEditField
        NumberofFlashesEditFieldLabel  matlab.ui.control.Label
        FlashButton                    matlab.ui.control.Button
        LEDOFFButton                   matlab.ui.control.Button
        LEDONButton                    matlab.ui.control.Button
    end


    methods (Access = private)

        function response = sendCommand(app, command)
            import matlab.net.*
            import matlab.net.http.*

            r = RequestMessage;
            uri = URI("http://137.112.197.154:5000/api/" + command);
            resp = send(r, uri);

            response = resp.Body.Data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            response = sendCommand(app, "led/on");
            app.ResponseTextArea.Value = response;
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            response = sendCommand(app, "led/off");
            app.ResponseTextArea.Value = response;
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            numFlashes = app.NumberofFlashesEditField.Value;
            periodMs = app.PeriodmsEditField.Value;

            command = "flash/" + num2str(numFlashes) + "/" + num2str(periodMs);

            response = sendCommand(app, command);
            app.ResponseTextArea.Value = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDONButton = uibutton(app.UIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.UIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.NumberofFlashesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofFlashesEditFieldLabel.Text = 'Number of Flashes';
            app.NumberofFlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResponseTextAreaLabel = uilabel(app.UIFigure);
            app.ResponseTextAreaLabel.Text = 'Response';
            app.ResponseTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end