classdef BYOBGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SpecialFeaturesPanel       matlab.ui.container.Panel
        FixLink0inSpaceButton      matlab.ui.control.Button
        TImetogetbacktoworkButton  matlab.ui.control.Button
        SavedPositionsPanel        matlab.ui.container.Panel
        Set3Button                 matlab.ui.control.Button
        Set2Button                 matlab.ui.control.Button
        Goto3Button                matlab.ui.control.Button
        Goto2Button                matlab.ui.control.Button
        Goto1Button                matlab.ui.control.Button
        Set1Button                 matlab.ui.control.Button
        Pos3_Label                 matlab.ui.control.Label
        Pos2_Label                 matlab.ui.control.Label
        Pos1_Label                 matlab.ui.control.Label
        ArmServosPanel             matlab.ui.container.Panel
        Theta6Label                matlab.ui.control.Label
        Theta5Label                matlab.ui.control.Label
        D4Label                    matlab.ui.control.Label
        Joint6EditField            matlab.ui.control.NumericEditField
        Joint5Label                matlab.ui.control.Label
        Joint5EditField            matlab.ui.control.NumericEditField
        Joint6Label                matlab.ui.control.Label
        Joint4EditField            matlab.ui.control.NumericEditField
        Joint4Label                matlab.ui.control.Label
        Theta5Slider               matlab.ui.control.Slider
        Theta6Slider               matlab.ui.control.Slider
        Theta4Slider               matlab.ui.control.Slider
        GOButton                   matlab.ui.control.Button
        RESETButton                matlab.ui.control.Button
        Joint3EditField            matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        Joint2EditField            matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        Joint1EditField            matlab.ui.control.NumericEditField
        Joint1EditFieldLabel       matlab.ui.control.Label
        Theta2Slider               matlab.ui.control.Slider
        Theta2SliderLabel          matlab.ui.control.Label
        Theta3Slider               matlab.ui.control.Slider
        Theta3SliderLabel          matlab.ui.control.Label
        Theta1Slider               matlab.ui.control.Slider
        Theta1SliderLabel          matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        link4Patch
        link4Vertices
        link5Patch
        link5Vertices
        link6Patch
        link6Vertices
        
        % j angles in deg and d4 in mm
        byobThetas = [0 0 0 482.6 0 0];

        savedAngles1 = [0 -15 -150 482.6 150 0];
        savedAngles2 = [0 0 0 482.6 0 0];
        savedAngles3 = [40 0.0 80 600 80 15.1];
    end
    
    methods (Access = private)
        
        function updateArm(app)
            % degrees and mm (j4)
            A1 = create_A_matrix(0, 0, -90, app.byobThetas(1));
            A2 = create_A_matrix(609.6, 0, 0, app.byobThetas(2));
            A3 = create_A_matrix(0, 0, -90, app.byobThetas(3) - 90);
            A4 = create_A_matrix(0, app.byobThetas(4), 90, 0);
            A5 = create_A_matrix(304.8, 0, 0, app.byobThetas(5) + 90);
            A6 = create_A_matrix(0, 0, 0, app.byobThetas(6));

            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1*A2;
            T0_3 = A1*A2*A3;
            T0_4 = A1*A2*A3*A4;
            T0_5 = A1*A2*A3*A4*A5;
            T0_6 = A1*A2*A3*A4*A5*A6;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            link4VerticesWRTground = T0_4 * app.link4Vertices;
            link5VerticesWRTground = T0_5 * app.link5Vertices;
            link6VerticesWRTground = T0_6 * app.link6Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            set(app.link4Patch,'Vertices', link4VerticesWRTground(1:3,:)');
            set(app.link5Patch,'Vertices', link5VerticesWRTground(1:3,:)');
            set(app.link6Patch,'Vertices', link6VerticesWRTground(1:3,:)');
            
           % Update sliders
           app.Theta1Slider.Value = app.byobThetas(1);
           app.Theta2Slider.Value = app.byobThetas(2);
           app.Theta3Slider.Value = app.byobThetas(3);
           app.Theta4Slider.Value = app.byobThetas(4);
           app.Theta5Slider.Value = app.byobThetas(5);
           app.Theta6Slider.Value = app.byobThetas(6);
            
           % Update edit fields
           app.Joint1EditField.Value = app.byobThetas(1);
           app.Joint2EditField.Value = app.byobThetas(2);
           app.Joint3EditField.Value = app.byobThetas(3);
           app.Joint4EditField.Value = app.byobThetas(4);
           app.Joint5EditField.Value = app.byobThetas(5);
           app.Joint6EditField.Value = app.byobThetas(6);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.UIAxes.View = [125 -20];
            app.UIAxes.CameraUpVector = [1 0 0];
            app.UIAxes.Visible = 'off';

            % % view limits
            % app.UIAxes.XLim = [-1800 1800];
            % app.UIAxes.YLim = [-1800 1800];
            % app.UIAxes.ZLim = [-1800 1800];


            % Create vertices for all the patches
            makeStlLink("link0.stl", app.UIAxes, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("link1.stl", app.UIAxes, [80/255 200/255 230/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("link2.stl", app.UIAxes, [240/255 190/255 40/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("link3.stl", app.UIAxes, [220/255 60/255 60/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.link4Patch = makeStlLink("link4.stl", app.UIAxes, [80/255 200/255 230/255]);
            app.link4Vertices = get(app.link4Patch, 'Vertices')';
            app.link4Vertices(4,:) = ones(1, size(app.link4Vertices,2));

            app.link5Patch = makeStlLink("link5.stl", app.UIAxes, [240/255 190/255 40/255]);
            app.link5Vertices = get(app.link5Patch, 'Vertices')';
            app.link5Vertices(4,:) = ones(1, size(app.link5Vertices,2));

            app.link6Patch = makeStlLink("endeff.stl", app.UIAxes, [220/255 60/255 60/255]);
            app.link6Vertices = get(app.link6Patch, 'Vertices')';
            app.link6Vertices(4,:) = ones(1, size(app.link6Vertices,2));


            % No mesh edges (too busy on these STLs); a headlight shades the parts instead.
            set(app.UIAxes.Children, 'EdgeColor', 'none');
            camlight(app.UIAxes, 'headlight');

            app.Pos1_Label.Text = num2str(app.savedAngles1);
            app.Pos2_Label.Text = num2str(app.savedAngles2);
            app.Pos3_Label.Text = num2str(app.savedAngles3);

            app.updateArm()

            enableDefaultInteractivity(app.UIAxes);
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Value changing function: Theta1Slider
        function Theta1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(1) = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta2Slider
        function Theta2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta3Slider
        function Theta3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(3) = changingValue;
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
           app.byobThetas(1) = app.Joint1EditField.Value;
           app.byobThetas(2) = app.Joint2EditField.Value;
           app.byobThetas(3) = app.Joint3EditField.Value;
           app.byobThetas(4) = app.Joint4EditField.Value;
           app.byobThetas(5) = app.Joint5EditField.Value;
           app.byobThetas(6) = app.Joint6EditField.Value;

           app.updateArm()
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
           app.byobThetas(1) = 0;
           app.byobThetas(2) = 0;
           app.byobThetas(3) = 0;
           app.byobThetas(4) = 482.6;
           app.byobThetas(5) = 0;
           app.byobThetas(6) = 0;

          app.updateArm()
        end

        % Button pushed function: Goto1Button
        function Goto1ButtonPushed(app, event)
            app.byobThetas(1) = app.savedAngles1(1);
            app.byobThetas(2) = app.savedAngles1(2);
            app.byobThetas(3) = app.savedAngles1(3);
            app.byobThetas(4) = app.savedAngles1(4);
            app.byobThetas(5) = app.savedAngles1(5);
            app.byobThetas(6) = app.savedAngles1(6);

            app.updateArm()
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedAngles1(1) = app.byobThetas(1);
            app.savedAngles1(2) = app.byobThetas(2);
            app.savedAngles1(3) = app.byobThetas(3);
            app.savedAngles1(4) = app.byobThetas(4);
            app.savedAngles1(5) = app.byobThetas(5);
            app.savedAngles1(6) = app.byobThetas(6);
            
            savedAngles1String = num2str(round(app.savedAngles1, 1));
            app.Pos1_Label.Text = savedAngles1String;
        end

        % Button pushed function: Goto2Button
        function Goto2ButtonPushed(app, event)
            app.byobThetas(1) = app.savedAngles2(1);
            app.byobThetas(2) = app.savedAngles2(2);
            app.byobThetas(3) = app.savedAngles2(3);
            app.byobThetas(4) = app.savedAngles2(4);
            app.byobThetas(5) = app.savedAngles2(5);
            app.byobThetas(6) = app.savedAngles2(6);

            app.updateArm()
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedAngles2(1) = app.byobThetas(1);
            app.savedAngles2(2) = app.byobThetas(2);
            app.savedAngles2(3) = app.byobThetas(3);
            app.savedAngles2(4) = app.byobThetas(4);
            app.savedAngles2(5) = app.byobThetas(5);
            app.savedAngles2(6) = app.byobThetas(6);

            savedAngles2String = num2str(round(app.savedAngles2, 1));
            app.Pos2_Label.Text = savedAngles2String;
        end

        % Button pushed function: Goto3Button
        function Goto3ButtonPushed(app, event)
            app.byobThetas(1) = app.savedAngles3(1);
            app.byobThetas(2) = app.savedAngles3(2);
            app.byobThetas(3) = app.savedAngles3(3);
            app.byobThetas(4) = app.savedAngles3(4);
            app.byobThetas(5) = app.savedAngles3(5);
            app.byobThetas(6) = app.savedAngles3(6);

            app.updateArm()
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedAngles3(1) = app.byobThetas(1);
            app.savedAngles3(2) = app.byobThetas(2);
            app.savedAngles3(3) = app.byobThetas(3);
            app.savedAngles3(4) = app.byobThetas(4);
            app.savedAngles3(5) = app.byobThetas(5);
            app.savedAngles3(6) = app.byobThetas(6);

            savedAngles3String = num2str(round(app.savedAngles3, 1));
            app.Pos3_Label.Text = savedAngles3String;
        end

        % Callback function
        function Theta4SliderValueChanged(app, event)

        end

        % Value changing function: Theta4Slider
        function Theta4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(4) = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta5Slider
        function Theta5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(5) = changingValue;
            app.updateArm()
        end

        % Value changing function: Theta6Slider
        function Theta6SliderValueChanging(app, event)
            changingValue = event.Value;
            app.byobThetas(6) = changingValue;
            app.updateArm()
        end

        % Button pushed function: FixLink0inSpaceButton
        function FixLink0inSpaceButtonPushed(app, event)
            app.UIAxes.XLim = [-1800 1800];
            app.UIAxes.YLim = [-1800 1800];
            app.UIAxes.ZLim = [-1800 1800];

            camzoom(app.UIAxes, 2);
        end

        % Button pushed function: TImetogetbacktoworkButton
        function TImetogetbacktoworkButtonPushed(app, event)
            app.byobThetas = [0 0 0 482.6 0 0];
            app.updateArm();

            % j1
            for angle = 0:1:40
                app.byobThetas(1) = angle;
                app.updateArm();
                pause(0.01);
            end

            % j3, d4, j5
            for step = 1:40
                app.byobThetas(3) = 1.5 * step;
                app.byobThetas(4) = 482.6 + 2.935 * step;
                app.byobThetas(5) = 2 * step;
                app.updateArm();
                pause(0.01);
            end

            % j3, j6
            for step = 1:20
                app.byobThetas(3) = 60 + step;
                app.byobThetas(6) = 0.75 * step;
                app.updateArm();
                pause(0.01);
            end

            % grab
            pause(0.4);

            % j1 fixed
            for step = 1:10
                app.byobThetas(2) = -9 * step;
                app.byobThetas(3) = 80 - 8 * step;
                app.byobThetas(4) = 600 - 11.74 * step;
                app.byobThetas(5) = 80 - 8 * step;
                app.byobThetas(6) = 15 - 1.5 * step;
                app.updateArm();
                pause(0.005);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BYOB Arm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ArmServosPanel = uipanel(app.UIFigure);
            app.ArmServosPanel.Title = 'Arm Servos';
            app.Theta1SliderLabel = uilabel(app.ArmServosPanel);
            app.Theta1SliderLabel.Text = 'Theta 1';
            app.Theta1Slider = uislider(app.ArmServosPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.ValueChangingFcn = createCallbackFcn(app, @Theta1SliderValueChanging, true);
            app.Theta3SliderLabel = uilabel(app.ArmServosPanel);
            app.Theta3SliderLabel.Text = 'Theta 3';
            app.Theta3Slider = uislider(app.ArmServosPanel);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.ValueChangingFcn = createCallbackFcn(app, @Theta3SliderValueChanging, true);
            app.Theta2SliderLabel = uilabel(app.ArmServosPanel);
            app.Theta2SliderLabel.Text = 'Theta 2';
            app.Theta2Slider = uislider(app.ArmServosPanel);
            app.Theta2Slider.Limits = [-180 0];
            app.Theta2Slider.ValueChangingFcn = createCallbackFcn(app, @Theta2SliderValueChanging, true);
            app.Joint1EditFieldLabel = uilabel(app.ArmServosPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint1EditField.Limits = [-180 180];
            app.Joint2EditFieldLabel = uilabel(app.ArmServosPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint2EditField.Limits = [-180 0];
            app.Joint3EditFieldLabel = uilabel(app.ArmServosPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint3EditField.Limits = [-180 180];
            app.RESETButton = uibutton(app.ArmServosPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.GOButton = uibutton(app.ArmServosPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.Theta4Slider = uislider(app.ArmServosPanel);
            app.Theta4Slider.Limits = [482.6 685.8];
            app.Theta4Slider.ValueChangingFcn = createCallbackFcn(app, @Theta4SliderValueChanging, true);
            app.Theta4Slider.Value = 482.6;
            app.Theta6Slider = uislider(app.ArmServosPanel);
            app.Theta6Slider.Limits = [-180 180];
            app.Theta6Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta6Slider.ValueChangingFcn = createCallbackFcn(app, @Theta6SliderValueChanging, true);
            app.Theta5Slider = uislider(app.ArmServosPanel);
            app.Theta5Slider.Limits = [-180 180];
            app.Theta5Slider.ValueChangingFcn = createCallbackFcn(app, @Theta5SliderValueChanging, true);
            app.Joint4Label = uilabel(app.ArmServosPanel);
            app.Joint4Label.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint4EditField.Limits = [482.6 685.8];
            app.Joint4EditField.Value = 482.6;
            app.Joint6Label = uilabel(app.ArmServosPanel);
            app.Joint6Label.Text = 'Joint 6';
            app.Joint5EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint5EditField.Limits = [-180 180];
            app.Joint5Label = uilabel(app.ArmServosPanel);
            app.Joint5Label.Text = 'Joint 5';
            app.Joint6EditField = uieditfield(app.ArmServosPanel, 'numeric');
            app.Joint6EditField.Limits = [-180 180];
            app.D4Label = uilabel(app.ArmServosPanel);
            app.D4Label.Text = 'D 4';
            app.Theta5Label = uilabel(app.ArmServosPanel);
            app.Theta5Label.Text = 'Theta 5';
            app.Theta6Label = uilabel(app.ArmServosPanel);
            app.Theta6Label.Text = 'Theta 6';
            app.SavedPositionsPanel = uipanel(app.UIFigure);
            app.SavedPositionsPanel.Title = 'Saved Positions';
            app.Pos1_Label = uilabel(app.SavedPositionsPanel);
            app.Pos1_Label.Text = '0 0 0 0 0 0';
            app.Pos2_Label = uilabel(app.SavedPositionsPanel);
            app.Pos2_Label.Text = '0 0 0 0 0 0';
            app.Pos3_Label = uilabel(app.SavedPositionsPanel);
            app.Pos3_Label.Text = '0 0 0 0 0 0';
            app.Set1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.Goto1Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto1Button.ButtonPushedFcn = createCallbackFcn(app, @Goto1ButtonPushed, true);
            app.Goto1Button.Text = 'Go to 1';
            app.Goto2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto2Button.ButtonPushedFcn = createCallbackFcn(app, @Goto2ButtonPushed, true);
            app.Goto2Button.Text = 'Go to 2';
            app.Goto3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Goto3Button.ButtonPushedFcn = createCallbackFcn(app, @Goto3ButtonPushed, true);
            app.Goto3Button.Text = 'Go to 3';
            app.Set2Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.Set3Button = uibutton(app.SavedPositionsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.SpecialFeaturesPanel = uipanel(app.UIFigure);
            app.SpecialFeaturesPanel.Title = 'Special Features!';
            app.TImetogetbacktoworkButton = uibutton(app.SpecialFeaturesPanel, 'push');
            app.TImetogetbacktoworkButton.ButtonPushedFcn = createCallbackFcn(app, @TImetogetbacktoworkButtonPushed, true);
            app.TImetogetbacktoworkButton.Text = 'TIme to get back to work!';
            app.FixLink0inSpaceButton = uibutton(app.SpecialFeaturesPanel, 'push');
            app.FixLink0inSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @FixLink0inSpaceButtonPushed, true);
            app.FixLink0inSpaceButton.Text = 'Fix Link0 in Space';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BYOBGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end