classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SendButton            matlab.ui.control.Button
        PayloadTextArea       matlab.ui.control.TextArea
        PayloadTextAreaLabel  matlab.ui.control.Label
        TypeTextArea          matlab.ui.control.TextArea
        TypeTextAreaLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Received: %s \n", message)
            messageStruct = jsondecode(message);
            messagePayload = messageStruct.payload
        
        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            messageStruct.payload = messagePayload;
            messageJson = jsonencode(messageStruct);
            app.mqClient.write("chat", messageJson)
            fprintf("sent: %s\n", messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");

            app.mqClient.subscribe("chat", "Callback", @app.myCallback)

        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.sendMessage(app.TypeTextArea.Value, app.PayloadTextArea.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TypeTextAreaLabel = uilabel(app.UIFigure);
            app.TypeTextAreaLabel.Text = 'Type';
            app.TypeTextArea = uitextarea(app.UIFigure);
            app.PayloadTextAreaLabel = uilabel(app.UIFigure);
            app.PayloadTextAreaLabel.Text = 'Payload';
            app.PayloadTextArea = uitextarea(app.UIFigure);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end